classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
        DropDown2            matlab.ui.control.DropDown
        DropDown2Label       matlab.ui.control.Label
        Switch               matlab.ui.control.Switch
        SwitchLabel          matlab.ui.control.Label
        Lamp                 matlab.ui.control.Lamp
        LampLabel            matlab.ui.control.Label
        SimulationProgress   simulink.ui.control.SimulationProgress
        CoorDropDown         matlab.ui.control.DropDown
        CoorDropDownLabel    matlab.ui.control.Label
        TargetDropDown       matlab.ui.control.DropDown
        TargetDropDownLabel  matlab.ui.control.Label
        EditField            matlab.ui.control.NumericEditField
        EditFieldLabel       matlab.ui.control.Label
        DropDown             matlab.ui.control.DropDown
        DropDownLabel        matlab.ui.control.Label
        RunButton            matlab.ui.control.Button
        Slider               matlab.ui.control.Slider
        SliderLabel          matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
        UIAxes3D             matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        backend = AppBackend();
        IsRunning = false;
        
        %graphique
        targets;
        heads;
        quiver_handles;
    end
    
    methods (Access = private)
        
        function update(app)
            app.backend.update();
            %drawnow limitrate;
            app.updatePlot();
        end
        
        function RunSimulation(app)
            while app.IsRunning
                app.update();
                drawnow;
                pause(0.01);
            end

        end
        
        function InitPlot(app)
            app.UIAxes3D.XLim = [-150, 150];
            app.UIAxes3D.YLim = [-150, 150];
            app.UIAxes3D.ZLim = [-10, 150];
            app.UIAxes3D.View = [45, 30];

            xlabel(app.UIAxes3D, 'X');  
            ylabel(app.UIAxes3D, 'Y');  
            zlabel(app.UIAxes3D, 'Altitude (Z)'); 
            title(app.UIAxes3D, 'Rendu de Simulation en Temps Réel'); 
            
            hold(app.UIAxes3D, 'on');

            fill3(app.UIAxes3D, app.backend.env.GroundCoordinates.x, app.backend.env.GroundCoordinates.y, app.backend.env.GroundCoordinates.z, ...
                [0.5 0.5 0.5], 'FaceAlpha', 0.3); % Couleur grise avec transparence

            %Affichage des zones (obstacles)
            for i = 1:length(app.backend.env.ZonesList)
                zone = app.backend.env.ZonesList{i};
        
                % Déterminer la couleur selon la catégorie de la zone
                switch zone.Category
                    case 'A'
                        zoneColor = 'g'; % Zones autorisées en vert
                    case 'P'
                        zoneColor = 'r'; % Zones prohibées en rouge
                    case 'M'
                        zoneColor = 'b'; % Zones de manœuvre en bleu
                    otherwise
                        zoneColor = 'y'; % Autres zones en jaune
                end
        
                % Créer la forme de la zone avec le type, la couleur, la position et les dimensions spécifiées
                create_shape(zone.Type, zoneColor, zone.CenterPosition, zone.Dimensions, zone.TiltAngle, app.UIAxes3D);
            end
            
            %Affichage des drones et des targets
            targets = app.backend.targetList;
            app.targets = gobjects(1, size(targets,1));
            for k = 1:size(targets,1)
                app.targets(k)=scatter3(app.UIAxes3D, targets(k,1), targets(k,2), targets(k,3), 50, 'filled', 'MarkerFaceColor', 'r');
            end
        
            n_drone=size(app.backend.swarm.Drones,2);
            drone_pos=zeros(n_drone,3);
            for i=1:n_drone
                drone_pos(i,:)=app.backend.swarm.Drones{i}.posState;
        
            end
        
            % Initialiser les objets de tracé pour les éléments animés    
            app.heads=gobjects(1,n_drone);
            app.quiver_handles=gobjects(1,n_drone);
        
            for i=1:n_drone
                app.heads(i)=scatter3(app.UIAxes3D, drone_pos(i,1), drone_pos(i,2), drone_pos(i,3), 50, 'filled', 'MarkerFaceColor', 'b');
                app.quiver_handles(i)=quiver3(app.UIAxes3D, drone_pos(i,1), drone_pos(i,2), drone_pos(i,3), 0, 0, 0, 'r', 'LineWidth', 1.5, 'MaxHeadSize', 0.5);
            end
        end


        function updatePlot(app)
            hold(app.UIAxes3D, 'on');
            n_drone=size(app.backend.swarm.Drones,2);
            drone_pos=zeros(n_drone,3);
            drone_speed=zeros(n_drone,3);
            for i=1:n_drone
                drone_pos(i,:)=app.backend.swarm.Drones{i}.posState;
                drone_speed(i,:)=app.backend.swarm.Drones{i}.speedState;
            end
    
            for i=1:n_drone
                 set(app.heads(i), 'XData', drone_pos(i,1), 'YData', drone_pos(i,2), 'ZData', drone_pos(i,3));
                 set(app.quiver_handles(i), 'XData', drone_pos(i,1), 'YData', drone_pos(i,2), 'ZData', drone_pos(i,3), ...
                     'UData', drone_speed(i,1), 'VData', drone_speed(i,2), 'WData', drone_speed(i,3));
            end
        end
        
        function InitDropDown(app)
            targetNames = {};
            for i = 1:size(app.backend.targetList, 1)
                targetNames{end+1} = ['target', num2str(i), '_x'];
                targetNames{end+1} = ['target', num2str(i), '_y'];
                targetNames{end+1} = ['target', num2str(i), '_z'];
            end
            app.DropDown.Items = targetNames;
            dropdown.Value = 'target1_x';
        end
        function InitSlider(app)
            %app.Slider.Limits = app.backend.env.GroundDimensions;
            app.Slider.Limits = [-200 200];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.backend.Init();
            app.InitPlot();
            app.InitDropDown();
            app.InitSlider();

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            if ~app.IsRunning
                app.IsRunning = true;
                app.RunButton.Text = 'Pause';
                RunSimulation(app);
            else
                app.IsRunning = false;
                app.RunButton.Text = 'Run';
            end


        end

        % Clicked callback: DropDown
        function DropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.UIAxes3D = uiaxes(app.UIFigure);
            title(app.UIAxes3D, '3DGraph')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            zlabel(app.UIAxes3D, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Target1_x', 'Target1_y', 'Target1_z', 'Target2_x', 'Target2_y', 'Target2_z'};
            app.DropDown.ClickedFcn = createCallbackFcn(app, @DropDownClicked, true);
            app.DropDown.Value = 'Target1_x';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetDropDownLabel = uilabel(app.UIFigure);
            app.TargetDropDownLabel.Text = 'Target';
            app.TargetDropDown = uidropdown(app.UIFigure);
            app.TargetDropDown.Items = {'1', '2'};
            app.TargetDropDown.Value = '1';
            app.CoorDropDownLabel = uilabel(app.UIFigure);
            app.CoorDropDownLabel.Text = 'Coor.';
            app.CoorDropDown = uidropdown(app.UIFigure);
            app.CoorDropDown.Items = {'x', 'y', 'z'};
            app.CoorDropDown.Value = 'x';
            app.SimulationProgress = uisimprogress(app.UIFigure);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.DropDown2Label = uilabel(app.UIFigure);
            app.DropDown2Label.Text = 'Drop Down2';
            app.DropDown2 = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end